classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Method2DropDown         matlab.ui.control.DropDown
        Method2DropDownLabel    matlab.ui.control.Label
        Method1DropDown         matlab.ui.control.DropDown
        Method1DropDownLabel    matlab.ui.control.Label
        ReescaleEditField       matlab.ui.control.EditField
        ReescaleEditFieldLabel  matlab.ui.control.Label
        M2Label                 matlab.ui.control.Label
        Image_3                 matlab.ui.control.Image
        M1Label                 matlab.ui.control.Label
        Image_2                 matlab.ui.control.Image
        OriginalLabel           matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        AjustarButton           matlab.ui.control.Button
        LoadImageButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        A % Charge Image
        fullPathOrigin
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png'}, "Select Image");
            app.fullPathOrigin = strcat(path, file);
            app.A = imread(app.fullPathOrigin);
        end

        % Button pushed function: AjustarButton
        function AjustarButtonPushed(app, event)
            scale = lower(app.ReescaleEditField.Value);
            methodInterp1 = lower(app.Method1DropDown.Value);
            methodInterp2 = lower(app.Method2DropDown.Value);

            F = griddedInterpolant(double(app.A));
            [ sx , sy , sz ] = size(app.A);
            xq = ( 1:0.5:sx )';
            yq = ( 1:0.5:sy )';
            zq = ( 1:sz )';

            F.Method = methodInterp1;
            vq = uint8(F({ xq , yq , zq }));
            imwrite(vq, 'm1.png');
            pathDefault = imread("m1.png");

            F.Method = methodInterp2; % define el metodo de interpolacion
            vq = uint8(F({ xq , yq , zq }));  % crea la nueva imagen con la interpolacion
            imwrite(vq, 'm2.png');
            pathInterpolation = imread("m2.png");

            app.Image.ImageSource = app.fullPathOrigin;
            app.Image_2.ImageSource = pathDefault;
            app.Image_3.ImageSource = pathInterpolation;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AjustarButton = uibutton(app.UIFigure, 'push');
            app.AjustarButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarButtonPushed, true);
            app.AjustarButton.Text = 'Ajustar';
            app.Image = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.Image_2 = uiimage(app.UIFigure);
            app.M1Label = uilabel(app.UIFigure);
            app.M1Label.Text = 'M1';
            app.Image_3 = uiimage(app.UIFigure);
            app.M2Label = uilabel(app.UIFigure);
            app.M2Label.Text = 'M2';
            app.ReescaleEditFieldLabel = uilabel(app.UIFigure);
            app.ReescaleEditFieldLabel.Text = 'Reescale %';
            app.ReescaleEditField = uieditfield(app.UIFigure, 'text');
            app.ReescaleEditField.Value = '200';
            app.Method1DropDownLabel = uilabel(app.UIFigure);
            app.Method1DropDownLabel.Text = 'Method 1:';
            app.Method1DropDown = uidropdown(app.UIFigure);
            app.Method1DropDown.Items = {'Linear', 'Cubic', 'Spline', 'Nearest'};
            app.Method1DropDown.Value = 'Linear';
            app.Method2DropDownLabel = uilabel(app.UIFigure);
            app.Method2DropDownLabel.Text = 'Method 2:';
            app.Method2DropDown = uidropdown(app.UIFigure);
            app.Method2DropDown.Items = {'Linear', 'Cubic', 'Spline', 'Nearest'};
            app.Method2DropDown.Value = 'Nearest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end